classdef MATLAB_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DownloadDatasetButton      matlab.ui.control.Button
        SelectnationDropDownLabel  matlab.ui.control.Label
        SelectnationDropDown       matlab.ui.control.DropDown
        SavechartsCheckBox         matlab.ui.control.CheckBox
        Label                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DownloadDatasetButton
        function DownloadDatasetButtonPushed(app, event)
            confirmed_global_file = websave('time_series_covid19_confirmed_global.csv','https://data.humdata.org/hxlproxy/api/data-preview.csv?url=https%3A%2F%2Fraw.githubusercontent.com%2FCSSEGISandData%2FCOVID-19%2Fmaster%2Fcsse_covid_19_data%2Fcsse_covid_19_time_series%2Ftime_series_covid19_confirmed_global.csv&filename=time_series_covid19_confirmed_global.csv');
            deaths_global_file = websave('time_series_covid19_deaths_global.csv','https://data.humdata.org/hxlproxy/api/data-preview.csv?url=https%3A%2F%2Fraw.githubusercontent.com%2FCSSEGISandData%2FCOVID-19%2Fmaster%2Fcsse_covid_19_data%2Fcsse_covid_19_time_series%2Ftime_series_covid19_deaths_global.csv&filename=time_series_covid19_deaths_global.csv');
            recovered_global_file = websave('time_series_covid19_recovered_global.csv','https://data.humdata.org/hxlproxy/api/data-preview.csv?url=https%3A%2F%2Fraw.githubusercontent.com%2FCSSEGISandData%2FCOVID-19%2Fmaster%2Fcsse_covid_19_data%2Fcsse_covid_19_time_series%2Ftime_series_covid19_recovered_global.csv&filename=time_series_covid19_recovered_global.csv');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DownloadDatasetButton = uibutton(app.UIFigure, 'push');
            app.DownloadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDatasetButtonPushed, true);
            app.DownloadDatasetButton.Text = 'Download Dataset';
            app.SelectnationDropDownLabel = uilabel(app.UIFigure);
            app.SelectnationDropDownLabel.Text = 'Select nation';
            app.SelectnationDropDown = uidropdown(app.UIFigure);
            app.SelectnationDropDown.Items = {'confirmed_global_file'};
            app.SelectnationDropDown.Value = 'confirmed_global_file';
            app.SavechartsCheckBox = uicheckbox(app.UIFigure);
            app.SavechartsCheckBox.Text = 'Save charts';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'The data are available at https://data.humdata.org/dataset/novel-coronavirus-2019-ncov-cases';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATLAB_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end